classdef Karaoke_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        TracksDropDownLabel   matlab.ui.control.Label
        TracksDropDown        matlab.ui.control.DropDown
        InputTypeButtonGroup  matlab.ui.container.ButtonGroup
        LiveButton            matlab.ui.control.RadioButton
        RecordedButton        matlab.ui.control.RadioButton
        PLAYButton            matlab.ui.control.Button
        STOPButton            matlab.ui.control.Button
        PAUSEButton           matlab.ui.control.Button
        LyricsTextAreaLabel   matlab.ui.control.Label
        LyricsTextArea        matlab.ui.control.TextArea
        Clock                 matlab.ui.control.TextArea
        ScoreTextAreaLabel    matlab.ui.control.Label
        ScoreTextArea         matlab.ui.control.TextArea
        TimeLabel             matlab.ui.control.Label
        KARAOKELabel          matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % ASIO Driver if necessary.
        SamplesPerFrame = 4410;  % Increase it to reduce overrun and underrun and decrease it to reduce latency.
        
        % Initializations
        
        threshold_s = 0.6;
        threshold_b = 1.4;
        
        isStop = 0;
        isPaused = 0;
        
    end
    
    methods (Access = private)
        
        function sync(~, name1, audio1, Fs1, name2, audio2, Fs2)
            [c, lags] = xcorr(audio2(:, 1), audio1(:, 1));
            [~, index] = max(c);
            delay = lags(index);
            
            audio2 = (audio2(:, 1) + audio2(:, 2))/2;
            audio1 = (audio1(:, 1) + audio1(:, 2))/2;
            
            while delay ~= 0
                if delay > 0    
                    audio2 = audio2(delay+1:end);
                    
                    if length(audio1) < length(audio2)
                        audio2 = audio2(1:length(audio1)); 
                    else
                        audio1 = audio1(1:length(audio2));
                    end           
            
                    [c2, lags2] = xcorr(audio2, audio1, 'normalized');
                    [~, index2] = max(c2);
                    delay = lags2(1, index2);
                else
                    audio2 = [zeros(abs(delay), 1); audio2];
                    
                    if length(audio1) < length(audio2)
                        audio2 = audio2(1:length(audio1)); 
                    else
                        audio1 = audio1(1:length(audio2));
                    end        
            
                    [c2, lags2] = xcorr(audio2,audio1,'normalized');
                    [~, index2] = max(c2);
                    delay = lags2(1, index2);       
                end        
            end
            
            audiowrite(strcat('Sync\', name1, '.wav'), audio1, Fs1);
            audiowrite(strcat('Sync\', name2, '.wav'), audio2, Fs2);
            
        end
        
        function [energies_vocal, energies_record] = lyrics(app, counter, score, energies_vocal, energies_record)
            seconds = counter/10;
            if seconds == 1
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪♪♪';
                
            elseif seconds == 7
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ SPENT 24 HOURS, I NEED MORE HOURS WITH YOU ♪';
                
            elseif seconds == 14
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YOU SPENT THE WEEKEND GETTING EVEN, OOH ♪';
                
            elseif seconds == 22
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WE SPENT THE LATE NIGHTS MAKING THINGS RIGHT BETWEEN US ♪';
            
            elseif seconds == 30
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ BUT NOW IT''S ALL GOOD, BABE ♪';
            
            elseif seconds == 32
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ROLL THAT BACK WOOD, BABE ♪';
            
            elseif seconds == 34
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ AND PLAY ME CLOSE ♪';
            
            elseif seconds == 38
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''CAUSE GIRLS LIKE YOU RUN'' ROUND WITH GUYS LIKE ME ♪';
          
            elseif seconds == 41
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''TIL SUN DOWN WHEN I COME THROUGH ♪';
           
            elseif seconds == 43
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
          
            elseif seconds == 46
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ GIRLS LIKE YOU LOVE FUN, AND YEAH, ME TOO ♪';
           
            elseif seconds == 49
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WHAT I WANT WHEN I COME THROUGH ♪';
          
            elseif seconds == 51
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
           
            elseif seconds == 55
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
           
            elseif seconds == 59
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
          
            elseif seconds == 62
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
           
            elseif seconds == 66
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU ♪';
           
            elseif seconds == 68
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I SPENT LAST NIGHT ON THE LAST FLIGHT TO YOU ♪';
           
            elseif seconds == 76
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ TOOK A WHOLE DAY UP TRYNA GET WAY UP, OOH ♪';
           
            elseif seconds == 83
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WE SPENT THE DAYLIGHT TRYNA MAKE THINGS RIGHT BETWEEN US ♪';
           
            elseif seconds == 91
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ BUT NOW IT''S ALL GOOD, BABE ♪';
          
            elseif seconds == 93
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ROLL THAT BACK WOOD, BABE ♪';
          
            elseif seconds == 95
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ AND PLAY ME CLOSE ♪';
           
            elseif seconds == 99
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''CAUSE GIRLS LIKE YOU RUN'' ROUND WITH GUYS LIKE ME ♪';
           
            elseif seconds == 103
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''TIL SUN DOWN WHEN I COME THROUGH ♪';
          
            elseif seconds == 105
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
           
            elseif seconds == 107
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ GIRLS LIKE YOU LOVE FUN, AND YEAH, ME TOO ♪';
            
            elseif seconds == 110
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WHAT I WANT WHEN I COME THROUGH ♪';
            
            elseif seconds == 112
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
           
            elseif seconds == 116
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
           
            elseif seconds == 120
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
           
            elseif seconds == 124
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
           
            elseif seconds == 128
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
          
            elseif seconds == 135
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
           
            elseif seconds == 143
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU ♪';
           
            elseif seconds == 146
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE IT''S 6:45 ♪';
          
            elseif seconds == 148
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE I''M BARELY ALIVE ♪';
          
            elseif seconds == 150
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE YOU''VE TAKEN MY SHIT FOR THE LAST TIME, YEAH ♪';
          
            elseif seconds == 154
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE I KNOW THAT I''M DRUNK ♪';
          
            elseif seconds == 155
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE I KNOW YOU''RE THE ONE ♪';
          
            elseif seconds == 157
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ MAYBE YOU''RE THINKING IT''S BETTER IF YOU DRIVE ♪';
          
            elseif seconds == 162
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ OH ''CAUSE GIRLS LIKE YOU RUN'' ROUND WITH GUYS LIKE ME ♪';
          
            elseif seconds == 166
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''TIL SUN DOWN WHEN I COME THROUGH ♪';
         
            elseif seconds == 168
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH ♪';
          
            elseif seconds == 172
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ NOT TOO LONG AGO I WAS DANCIN'' FOR DOLLARS ♪';
          
            elseif seconds == 174
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ KNEW IT WAS REALLY REAL IF I LET YOU MET MY MOMMA ♪';
           
            elseif seconds == 176
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YOU DON''T WANT A GIRL LIKE ME I''M TOO CRAZY ♪';
          
            elseif seconds == 178
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ BUT EVERY OTHER GIRL YOU MEET IS FUGAZY (OOOKKURRT) ♪';
         
            elseif seconds == 180
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I''M SURE THEM OTHER GIRLS WERE NICE ENOUGH ♪';
          
            elseif seconds == 182
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ BUT YOU NEED SOMEONE TO SPICE IT UP ♪';
           
            elseif seconds == 184
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ SO WHO YOU GONNA CALL CARDI CARDI ♪';
          
            elseif seconds == 186
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ TO COME AND REV IT UP LIKE A HARLEY HARLEY ♪';
           
            elseif seconds == 188
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WHY IS THE BEST FRUIT ALWAYS FORBIDDEN ♪';
          
            elseif seconds == 190
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I''M COMING TO YOU NOW DOIN'' 20 OVER THE LIMIT ♪';
          
            elseif seconds == 191
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ THE RED LIGHT RED LIGHT STOP (STOP) ♪';
          
            elseif seconds == 193
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I DON''T PLAY WHEN IT COMES TO MY HEART, LETS GET IT THOUGH ♪';
          
            elseif seconds == 196
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I DON''T REALLY WANT A WHITE HORSE AND A CARRIAGE ♪';
          
            elseif seconds == 197
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I''M THINKING MORE WHITE PORSCHES AND KARATS ♪';
          
            elseif seconds == 199
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED YOU RIGHT HERE CAUSE EVERY TIME YOU FALL ♪';
          
            elseif seconds == 201
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I PLAY WITH THIS KITTY LIKE YOU PLAY WIT YOUR GUITAR ♪';
           
            elseif seconds == 203
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''CAUSE GIRLS LIKE YOU RUN ''ROUND WITH GUYS LIKE ME ♪';
         
            elseif seconds == 206
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ ''TIL SUN DOWN WHEN I COME THROUGH ♪';
        
            elseif seconds == 208
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
         
            elseif seconds == 211
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ GIRLS LIKE YOU LOVE FUN, AND YEAH, ME TOO ♪';
           
            elseif seconds == 214
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ WHAT I WANT WHEN I COME THROUGH ♪';
           
            elseif seconds == 216
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
            
            elseif seconds == 220
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
            
            elseif seconds == 224
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU, YEAH YEAH ♪';
            
            elseif seconds == 227
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
            
            elseif seconds == 231
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU ♪';
            
            elseif seconds == 235
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ YEAH YEAH YEAH, YEAH YEAH YEAH ♪';
            
            elseif seconds == 239
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪ I NEED A GIRL LIKE YOU ♪';
            
            elseif seconds == 241
                app.ScoreTextArea.Value = score;
                energies_vocal = [];
                energies_record = [];
                app.LyricsTextArea.Value= '♪♪♪';
            end


%             filename = strcat('Lyrics\', app.TracksDropDown.Value, '.txt');
%             lyric_file = fopen(filename);
%             lyric_cell = textscan(lyric_file, '%s', 'Delimiter', '\n');
%             
%             pause_duration = str2double(lyric_cell{1}(1));
%             
%             if counter/10 >= pause_duration
%                 if mod(counter-pause_duration*10, 50) == 0
%                     verse = (counter-pause_duration*10)/50 + 2;
%                     cellArrayText{1} = lyric_cell{1}{verse};
%                     cellArrayText{2} = lyric_cell{1}{verse+1};
%                     app.LyricsTextArea.Value= cellArrayText;
%                 end
%             end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tracks = struct2cell(dir('Tracks\*.mp3'));
            tracks_name = tracks(1,:);
            app.TracksDropDown.Items = tracks_name;
            
            if isempty(app.TracksDropDown.Items) == 1
                uialert(app.UIFigure,...
                     ['Your current folder does not contain any audio files'],...
                     'Info','Icon','info');
            end
            
            app.Image.ImageSource = strcat(app.TracksDropDown.Value, '.jpeg');
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            clc; 
            counter = 0;        
            
            energies_vocal = [];
            energies_record = [];
            pitches_vocal = [];
            pitches_record = [];
            
            success_energies = 0;
            success_pitch = [];
            success_pitches = [];
            
            totalUnderrun = 0;
            totalOverrun = 0;
            
            app.Clock.Value = '0';          
            app.LyricsTextArea.Value = {'Karaoke is Loading...'};
            
            %% Directories
            track_directory = strcat('Tracks\', char(app.TracksDropDown.Value));
            cover_directory = strcat('Covers\', char(app.TracksDropDown.Value));
            background_directory = strcat('Backgrounds\', char(app.TracksDropDown.Value));
            vocal_directory = strcat('Vocals\', char(app.TracksDropDown.Value));
%             record_directory = strcat('Records\', char(app.TracksDropDown.Value));

            %% Load Sounds
            [track_audio, Fs_t] = audioread(track_directory);
            
            Hs = hamming(app.SamplesPerFrame, 'symmetric');
            
            if app.RecordedButton.Value == 1
                
                [cover_audio, Fs_c] = audioread(cover_directory);
                
                sync(app, 'sync_track', track_audio, Fs_t, 'sync_cover', cover_audio, Fs_c);
                
                %% Set Readers and Writers
                Track = dsp.AudioFileReader('Filename', 'Sync\sync_track.wav', ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);
                
                Cover = dsp.AudioFileReader('Filename', 'Sync\sync_cover.wav', ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);
                
                Speaker = audioDeviceWriter('SampleRate',Track.SampleRate);

                % Check Sampling Rates
                Fs_t = Track.SampleRate;
                Fs_c = Cover.SampleRate;
                
                if Fs_t ~= Fs_c
                    uialert(app.UIFigure,'Tracks have different sampling rates!',...
                        'Error','Icon','error'); 
                end
                
                % Pre Execute 1-time tasks before loop
                setup(Track)
                setup(Cover)
                setup(Speaker, zeros(Track.SamplesPerFrame, 1))
                
                app.LyricsTextArea.Value = {'Ready for Singing!'};
                tic
                while(1)
                    counter = counter + 1;
                    
                    if mod(counter, 10) == 0
                        if counter > 599
                            seconds = mod(counter, 600);
                        else
                            seconds = counter;
                        end
                        app.Clock.Value = strcat(int2str(floor(counter/600)), ':', int2str(seconds/10));
                    end
                    
%                     lyrics(app, counter);


                    my_track = Track();
                    my_cover = Cover();
                    
                    my_vocal=my_track;
                    my_record=my_cover;
                    
                    my_output = my_cover + my_track;
                    
                    
                    plot(app.UIAxes,[my_track, my_cover])
                    drawnow();
                    
                    % Single Energy 
                    smoothed_my_record =  my_record .* Hs;
                    energy_my_record = sum(sum(smoothed_my_record.^2))/2;
                
                    smoothed_my_vocal = my_vocal .* Hs;
                    energy_my_vocal = sum(sum(smoothed_my_vocal.^2))/2; 
            
%                     % Total Energy
%                     energies_vocal = [energies_vocal energy_my_vocal];
%                     energies_record = [energies_record energy_my_record];
%                     
%                     mean_vocal = mean(energies_vocal);
%                     mean_record = mean(energies_record);
%                     
%                     success_energy = 100*(1 - (abs(mean_record-mean_vocal)/(mean_vocal*0.6)));
%                     if success_energy < 0
%                         success_energy = 0;
%                     end
%                     
%                     if success_energy > 75
%                         score = 'Perfect!';
%                     elseif success_energy > 50
%                         score = 'Good!';
%                     elseif success_energy > 25
%                         score = 'Meh :(';
%                     else
%                         score = 'Bad...';
%                     end
%                     
%                     [energies_vocal, energies_record] = lyrics(app, counter-20, score, energies_vocal, energies_record);                   
                        
                    % Pitch            
                    
                    n = length(my_track);
                    fshift = (-n/2:n/2-1)*(Fs_t/n);
                    
                    Y = fft(my_track);
                    fftSignal1 = fftshift(Y);
                    [mag1, f0] = max(fftSignal1);
                    
                    Y = fft(my_cover);
                    fftSignal2 = fftshift(Y);
                    [mag2, f1] = max(fftSignal2);
                    
                    if mag1 < 1
                        f0 = 1;
                    end
                    
                    if mag2 < 1
                        f1 = 1;
                    end  
                    
                    
%                     speechIndices = detectSpeech(smoothed_my_vocal,Fs_t);
%                     f0 = [];
%                     for ii = 1:size(speechIndices,1)
%                         speechSegment = smoothed_my_vocal(speechIndices(ii,1):speechIndices(ii,2));
%                         f0 = [f0;pitch(speechSegment,Fs_t)];
%                     end
%                 
%                     speechIndices = detectSpeech(smoothed_my_record,Fs_t);
%                     f1 = [];
%                     for ii = 1:size(speechIndices,1)
%                         speechSegment = smoothed_my_record(speechIndices(ii,1):speechIndices(ii,2));
%                         f1 = [f1;pitch(speechSegment,Fs_t)];
%                     end
                    
%                     f0 = pitch(smoothed_my_vocal,Fs_t); 
%                     f1 = pitch(smoothed_my_record,Fs_t);
                    
                    % Total Energy
                    pitches_vocal = [pitches_vocal; f0];
                    pitches_record = [pitches_record; f1];
                    
                    mean_vocal = mean(pitches_vocal);
                    mean_record = mean(pitches_record);
                    
                    success_energy = 100*(1 - (abs(mean_record-mean_vocal)/(mean_vocal* 0.3)));
                    if success_energy < 0
                        success_energy = 0;
                    end
                    
                    if success_energy > 75
                        score = 'Perfect!';
                    elseif success_energy > 50
                        score = 'Good!';
                    elseif success_energy > 25
                        score = 'Meh :(';
                    else
                        score = 'Bad...';
                    end
                    
                    [pitches_vocal, pitches_record] = lyrics(app, counter-20, score, pitches_vocal, pitches_record);            
%                
                    Speaker(my_output);
                    
                    if app.isStop == 1
                        break;
                    end           
                end
                app.LyricsTextArea.Value = {'END!'};
                
                release(Track)
                release(Cover)
                release(Speaker)
                app.isStop = 0;

            elseif app.LiveButton.Value == 1
                [vocal_audio, Fs_v] = audioread(vocal_directory);
%                 [background_audio, Fs_b] = audioread(background_directory);
                
%                 sync(app, 'sync_track', track_audio, Fs_t, 'sync_background', background_audio, Fs_b);
                sync(app, 'sync_track', track_audio, Fs_t, 'sync_vocal', vocal_audio, Fs_v);
                
                
                %% Set Readers and Writers
                Track = dsp.AudioFileReader('Filename', 'Sync\sync_track.wav', ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);
                
                Background = dsp.AudioFileReader('Filename', 'Sync\sync_background.wav', ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);

                Vocal = dsp.AudioFileReader('Filename', 'Sync\sync_vocal.wav', ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);
                
                Recorder = audioDeviceReader('NumChannels', 1, 'SampleRate', Track.SampleRate, ...
                    'OutputDataType', 'double', 'SamplesPerFrame', app.SamplesPerFrame);
                
                Speaker = audioDeviceWriter('SampleRate',Track.SampleRate);
                
                % Check Sampling Rates
                Fs_t = Track.SampleRate;
                Fs_b = Background.SampleRate;
                Fs_v = Vocal.SampleRate;
                
                if Fs_t ~= Fs_b || Fs_t ~= Fs_v || Fs_b ~= Fs_v
                    uialert(app.UIFigure,'Tracks have different sampling rates!',...
                        'Error','Icon','error'); 
                end
                
                % Pre Execute 1-time tasks before loop
                setup(Track)
                setup(Background)
                setup(Vocal)
                setup(Recorder)
                setup(Speaker, zeros(Recorder.SamplesPerFrame, Recorder.NumChannels))

                if totalOverrun > 0
                    uialert(app.UIFigure,'There is Overrun! Consider increasing framesize.',...
                        'Error','Icon','error'); 
                end
               
                while(1)
                    counter = counter + 1;
                    
                    if mod(counter, 10) == 0
                        if counter > 599
                            seconds = mod(counter, 600);
                        else
                            seconds = counter;
                        end
                        app.Clock.Value = strcat(int2str(floor(counter/600)), ':', int2str(seconds/10));
                    end

                    my_track = Track();
%                     my_background = Background();
                    my_vocal = Vocal();
                    my_record = Recorder();
                    my_record = my_record*1.5;
                    
%                     if max(my_record) ~=0 
%                     my_record = my_record/max(my_record);
%                     end
%                     
%                     if max(my_vocal)~=0
%                     my_vocal = my_vocal/max(my_vocal);
%                     end
                    
                    my_output = my_vocal; %+my_track;
                    
                    plot(app.UIAxes,[my_vocal, my_record])
                    drawnow();
                    
                    % Single Energy 
                    smoothed_my_record =  my_record .* Hs;
                    energy_my_record = sum(sum(smoothed_my_record.^2))/2;
                
                    smoothed_my_vocal = my_vocal .* Hs;
                    energy_my_vocal = sum(sum(smoothed_my_vocal.^2))/2; 
%             
%                     % Total Energy
%                     energies_vocal = [energies_vocal energy_my_vocal];
%                     energies_record = [energies_record energy_my_record];
%                     
%                     mean_vocal = mean(energies_vocal);
%                     mean_record = mean(energies_record);
%                     
%                     success_energy = 100*(1 - (abs(mean_record-mean_vocal)/(mean_vocal* 0.6)));
%                     if success_energy < 0
%                         success_energy = 0;
%                     end
                
                    % Pitch
                    n = length(my_vocal);
                    fshift = (-n/2:n/2-1)*(Fs_v/n);
                    
                    Y = fft(my_vocal);
                    fftSignal1 = fftshift(Y);
                    [mag1, f0] = max(fftSignal1);
                    
                    Y = fft(my_record);
                    fftSignal2 = fftshift(Y);
                    [mag2, f1] = max(fftSignal2);
                    
                    if mag1 < 1
                        f0 = 1;
                    end
                    
                    if mag2 < 1
                        f1 = 1;
                    end
                    
                   
%                     plot([abs(fftSignal1),abs(fftSignal2)]);
%                      drawnow();
                                    
                    
%                     speechIndices = detectSpeech(smoothed_my_vocal,Fs_t);
%                     f0 = [];
%                     for ii = 1:size(speechIndices,1)
%                         speechSegment = smoothed_my_vocal(speechIndices(ii,1):speechIndices(ii,2));
%                         f0 = [f0;pitch(speechSegment,Fs_t)];
%                     end
%                 
%                     speechIndices = detectSpeech(smoothed_my_record,Fs_t);
%                     f1 = [];
%                     for ii = 1:size(speechIndices,1)
%                         speechSegment = smoothed_my_record(speechIndices(ii,1):speechIndices(ii,2));
%                         f1 = [f1;pitch(speechSegment,Fs_t)];
%                     end
                    
%                     f0 = pitch(smoothed_my_vocal,Fs_v); 
%                     f1 = pitch(smoothed_my_record,Fs_t);
                    
                    % Total Energy
                    pitches_vocal = [pitches_vocal; abs(fshift(f0))];
                    pitches_record = [pitches_record; abs(fshift(f1))];
                    
                    mean_vocal = mean(pitches_vocal);
                    mean_record = mean(pitches_record);
                    
                    success_energy = 100*(1 - (abs(mean_record-mean_vocal)/(mean_vocal* 0.2)));
                    if success_energy < 0
                        success_energy = 0;
                    end
                    
                    if success_energy > 75
                        score = 'Perfect!';
                    elseif success_energy > 50
                        score = 'Good!';
                    elseif success_energy > 25
                        score = 'Meh :(';
                    else
                        score = 'Bad...';
                    end
                    
                    [pitches_vocal, pitches_record] = lyrics(app, counter-10, score, pitches_vocal, pitches_record);
                    
                    
                    Speaker(my_output);
                    
                    if app.isStop == 1
                        break;
                    end   
                end   
                app.LyricsTextArea.Value = {'END!'};
            
                release(Track)
                release(Background)
                release(Vocal)
                release(Recorder)
                release(Speaker)
                app.isStop = 0;
            else
                uialert(app.UIFigure,...
                     ['Something is wrong!'],...
                     'Error','Icon','error');
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.isStop = 1;
            app.PAUSEButton.Text = 'PAUSE';
            app.isPaused = 0;
            uiresume(app.UIFigure);
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if app.isPaused == 0
                app.PAUSEButton.Text = 'RESUME';
                app.isPaused = 1;
                uiwait(app.UIFigure);
            else
                app.PAUSEButton.Text = 'PAUSE';
                app.isPaused = 0;
                uiresume(app.UIFigure);
            end
        end

        % Value changed function: TracksDropDown
        function TracksDropDownValueChanged(app, event)
            app.Image.ImageSource = strcat(app.TracksDropDown.Value, '.jpeg');       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Counting Stars-OneRepublic.mp3.jpeg';
            app.TracksDropDownLabel = uilabel(app.UIFigure);
            app.TracksDropDownLabel.Text = 'Tracks';
            app.TracksDropDown = uidropdown(app.UIFigure);
            app.TracksDropDown.ValueChangedFcn = createCallbackFcn(app, @TracksDropDownValueChanged, true);
            app.InputTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.InputTypeButtonGroup.Title = 'Input Type';
            app.LiveButton = uiradiobutton(app.InputTypeButtonGroup);
            app.LiveButton.Text = 'Live';
            app.LiveButton.Value = true;
            app.RecordedButton = uiradiobutton(app.InputTypeButtonGroup);
            app.RecordedButton.Text = 'Recorded';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY!';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP!';
            app.PAUSEButton = uibutton(app.UIFigure, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.LyricsTextAreaLabel = uilabel(app.UIFigure);
            app.LyricsTextAreaLabel.Text = 'Lyrics';
            app.LyricsTextArea = uitextarea(app.UIFigure);
            app.LyricsTextArea.Editable = 'off';
            app.Clock = uitextarea(app.UIFigure);
            app.Clock.Editable = 'off';
            app.ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.ScoreTextAreaLabel.Text = 'Score';
            app.ScoreTextArea = uitextarea(app.UIFigure);
            app.ScoreTextArea.Editable = 'off';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.KARAOKELabel = uilabel(app.UIFigure);
            app.KARAOKELabel.Text = 'KARAOKE';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Karaoke_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end